classdef deltaArdu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SerialPortsDropDown        matlab.ui.control.DropDown
        Image3                     matlab.ui.control.Image
        theta_3EditField           matlab.ui.control.NumericEditField
        theta_3Label               matlab.ui.control.Label
        theta_2EditField           matlab.ui.control.NumericEditField
        theta_2Label               matlab.ui.control.Label
        theta_1EditField           matlab.ui.control.NumericEditField
        theta_1Label               matlab.ui.control.Label
        BaudrateDropDown           matlab.ui.control.DropDown
        BaudrateLabel              matlab.ui.control.Label
        ConnectionLamp             matlab.ui.control.Lamp
        PulsesPanel                matlab.ui.container.Panel
        pulsepoint2EditField       matlab.ui.control.NumericEditField
        pulsepoint2EditFieldLabel  matlab.ui.control.Label
        pulsepoint1EditField       matlab.ui.control.NumericEditField
        pulsepoint1EditFieldLabel  matlab.ui.control.Label
        Z2EditField_3              matlab.ui.control.NumericEditField
        Z2EditField_2              matlab.ui.control.NumericEditField
        Y2EditField_2              matlab.ui.control.NumericEditField
        Y1EditField_2              matlab.ui.control.NumericEditField
        SerialPortsDropDownLabel   matlab.ui.control.Label
        Button                     matlab.ui.control.StateButton
        Image2                     matlab.ui.control.Image
        Z2EditField                matlab.ui.control.NumericEditField
        Z2EditFieldLabel           matlab.ui.control.Label
        X2EditField                matlab.ui.control.NumericEditField
        X2EditFieldLabel           matlab.ui.control.Label
        Y2EditField                matlab.ui.control.NumericEditField
        Y2EditFieldLabel           matlab.ui.control.Label
        Y1EditField                matlab.ui.control.NumericEditField
        Y1EditFieldLabel           matlab.ui.control.Label
        Z1EditField                matlab.ui.control.NumericEditField
        Z1EditFieldLabel           matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        CalculateButton            matlab.ui.control.StateButton
        X1EditField                matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        ContextMenu2               matlab.ui.container.ContextMenu
        endeffectorMenu            matlab.ui.container.Menu
        ContextMenu3               matlab.ui.container.ContextMenu
        Menu                       matlab.ui.container.Menu
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        portName
        baudrate
        s


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: SerialPortsDropDown
        function SerialPortsDropDownOpening(app, event)

            ports = serialportlist("available");
            app.SerialPortsDropDown.Items = ports;
            if ~isempty(ports)
                app.SerialPortsDropDown.Value = ports{1};
            end

        end

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
            % زوایای موتورهای مفصل‌ها (درجه)
            theta1 = app.theta_1EditField.Value;
            theta2 = app.theta_2EditField.Value;
            theta3 = app.theta_3EditField.Value ;

            % اجرای تابع Forward Kinematics
            [Xp, Yp, Zp] = delta_forward_kinematics(theta1, theta2, theta3);

            app.X1EditField.Value = Xp;
            app.Y1EditField.Value= Yp;
            app.Z1EditField.Value = Zp;
            point1_X = app.X1EditField.Value;
            point1_Y =app.Y1EditField.Value;
            point1_Z = app.Z1EditField.Value;
            point2_X = app.X2EditField.Value;
            point2_Y = app.Y2EditField.Value;
            point2_Z = app.Z2EditField.Value;
            point1_X = app.X1EditField.Value;
            point1_Y =app.Y1EditField.Value;
            point1_Z = app.Z1EditField.Value;
            point2_X = app.X2EditField.Value;
            point2_Y = app.Y2EditField.Value;
            point2_Z = app.Z2EditField.Value;


            [theta1, theta2, theta3, theta4, theta5, theta6] = transition(...
                point1_X, point1_Y, point1_Z, ...
                point2_X, point2_Y, point2_Z);

            % ==== تبدیل زوایا به پالس ====
            [pulse1, pulse2, pulse3] = pulseGenerator(theta1, theta2, theta3);
            [pulse4, pulse5, pulse6] = pulseGenerator(theta4, theta5, theta6);

            pulsepoint1 = [pulse1, pulse2, pulse3];
            pulsepoint2 = [pulse4, pulse5, pulse6];


            app.pulsepoint1EditField.Value = pulse1;
            app.Y1EditField_2.Value = pulse2;
            app.Z2EditField_3.Value = pulse3;
            app.pulsepoint2EditField.Value = pulse4;
            app.Y2EditField_2.Value = pulse5;
            app.Z2EditField_2.Value = pulse6;


        end

        % Callback function
        function UIAxesButtonDown(app, event)


        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            app.s = serialport(app.portName, app.baudrate);
            pause(2);

            sendPulseRow(app.s, pulsepoint1);
            pause(2);

            sendPulseRow(app.s, pulsepoint2);
            pause(2);


        end

        % Value changed function: SerialPortsDropDown
        function SerialPortsDropDownValueChanged(app, event)

            app.portName = app.SerialPortsDropDown.Value;
            try
                app.s = serialport(app.portName, app.baudrate);
                pause(2);
                app.ConnectionLamp.Color = 'green';
                uialert(app.UIFigure, "Arduino connected!", "Success");
            catch
                app.ConnectionLamp.Color = 'red';
                uialert(app.UIFigure, "Cannot connect to Arduino.", "Error");
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure, ...
                'Are you sure you want to exit?', ...
                'Confirm Exit', ...
                'Options', {'Yes', 'No'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            if strcmp(selection, 'Yes')
                delete(app)
            else
                return
            end

        end

        % Value changed function: BaudrateDropDown
        function BaudrateDropDownValueChanged(app, event)
            app.baudrate = str2double(app.BaudrateDropDown.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'DELTA.jpg');
            app.Z1EditFieldLabel = uilabel(app.UIFigure);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Z2EditFieldLabel = uilabel(app.UIFigure);
            app.Z2EditFieldLabel.Text = 'Z2';
            app.Z2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'download-removebg-preview (1).png');
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'download-removebg-preview.png');
            app.Button.Text = '';
            app.SerialPortsDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortsDropDownLabel.Text = 'Serial Ports';
            app.PulsesPanel = uipanel(app.UIFigure);
            app.PulsesPanel.Title = 'Pulses:';
            app.Y1EditField_2 = uieditfield(app.PulsesPanel, 'numeric');
            app.Y2EditField_2 = uieditfield(app.PulsesPanel, 'numeric');
            app.Z2EditField_2 = uieditfield(app.PulsesPanel, 'numeric');
            app.Z2EditField_3 = uieditfield(app.PulsesPanel, 'numeric');
            app.pulsepoint1EditFieldLabel = uilabel(app.PulsesPanel);
            app.pulsepoint1EditFieldLabel.Text = 'pulsepoint1';
            app.pulsepoint1EditField = uieditfield(app.PulsesPanel, 'numeric');
            app.pulsepoint2EditFieldLabel = uilabel(app.PulsesPanel);
            app.pulsepoint2EditFieldLabel.Text = 'pulsepoint2';
            app.pulsepoint2EditField = uieditfield(app.PulsesPanel, 'numeric');
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.BaudrateLabel = uilabel(app.UIFigure);
            app.BaudrateLabel.Text = 'Baudrate    ';
            app.BaudrateDropDown = uidropdown(app.UIFigure);
            app.BaudrateDropDown.Items = {'9600', '19200', '38400', '57600', '115200'};
            app.BaudrateDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudrateDropDownValueChanged, true);
            app.BaudrateDropDown.Value = '9600';
            app.theta_1Label = uilabel(app.UIFigure);
            app.theta_1Label.Interpreter = 'latex';
            app.theta_1Label.Text = '$\theta_1$: ';
            app.theta_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta_2Label = uilabel(app.UIFigure);
            app.theta_2Label.Interpreter = 'latex';
            app.theta_2Label.Text = '$\theta_2$ :';
            app.theta_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.theta_3Label = uilabel(app.UIFigure);
            app.theta_3Label.Interpreter = 'latex';
            app.theta_3Label.Text = '$\theta_3$ :';
            app.theta_3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'download-removebg-preview (1).png');
            app.SerialPortsDropDown = uidropdown(app.UIFigure);
            app.SerialPortsDropDown.Items = {'ports', 'Option 2', 'Option 3', 'Option 4'};
            app.SerialPortsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortsDropDownOpening, true);
            app.SerialPortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SerialPortsDropDownValueChanged, true);
            app.SerialPortsDropDown.Value = 'ports';
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.endeffectorMenu = uimenu(app.ContextMenu2);
            app.endeffectorMenu.Text = 'end effector زاویه اولیه ';
            % Assign app.ContextMenu2
            app.Image3.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu3);
            app.Menu.Text = 'مختصات ابتدا و انتهای ربات';
            % Assign app.ContextMenu3
            app.Image2.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deltaArdu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end